classdef MSpecAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSpecAnalysisUIFigure           matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        ToggleTool                      matlab.ui.container.toolbar.ToggleTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool7                       matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        AnalysisLabel                   matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ImportStatusLabel               matlab.ui.control.Label
        RecentFilesPanel                matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        RecentFileListBox               matlab.ui.control.ListBox
        CreateNewProjectPanel           matlab.ui.container.Panel
        DescriptionEditField            matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        Import_CreateProjectButton      matlab.ui.control.Button
        NumberofMassSpectraEditField    matlab.ui.control.NumericEditField
        NumberofMassSpectraEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        HeightField                     matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        WidthField                      matlab.ui.control.NumericEditField
        DimensionLabel                  matlab.ui.control.Label
        DataTypesButtonGroup            matlab.ui.container.ButtonGroup
        Imaging2DButton                 matlab.ui.control.RadioButton
        MassSpectra1DButton             matlab.ui.control.RadioButton
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        Import_ImportButton             matlab.ui.control.Button
        ImportyourmassspectrometrydatatobeginLabel  matlab.ui.control.Label
        WelcometoMSpecLabel             matlab.ui.control.Label
        ClassificationTab               matlab.ui.container.Tab
        StartButton                     matlab.ui.control.Button
        ImportedDataPanel               matlab.ui.container.Panel
        ImportedDataTable               matlab.ui.control.Table
        DataTypeLabel                   matlab.ui.control.Label
        DataTypeButtonGroup             matlab.ui.container.ButtonGroup
        Imaging2DButton_2               matlab.ui.control.RadioButton
        MassSpectra1DButton_2           matlab.ui.control.RadioButton
        Image_2                         matlab.ui.control.Image
        HeightField_2                   matlab.ui.control.NumericEditField
        xLabel_2                        matlab.ui.control.Label
        WidthField_2                    matlab.ui.control.NumericEditField
        DimensionLabel_2                matlab.ui.control.Label
        NumberofMassSpectraEditField_2  matlab.ui.control.NumericEditField
        NumberofMassSpectraEditField_2Label  matlab.ui.control.Label
        ModelInformationPanel           matlab.ui.container.Panel
        ModelInfoTextArea               matlab.ui.control.TextArea
        ImportModelButton               matlab.ui.control.Button
        ModelTypeLabel                  matlab.ui.control.Label
        ModelTypeTree                   matlab.ui.container.Tree
        KNEARESTNEIGHBORKNNNode         matlab.ui.container.TreeNode
        SUPPORTVECTORMACHINESSVMNode    matlab.ui.container.TreeNode
        DECISIONTREESNode               matlab.ui.container.TreeNode
        NAIVEBAYESCLASSIFIERSNode       matlab.ui.container.TreeNode
        ClassificationLabel             matlab.ui.control.Label
        PreprocessingTab                matlab.ui.container.Tab
        ViewOptionButtonGroup           matlab.ui.container.ButtonGroup
        Normalization_MultipleSpectraButton  matlab.ui.control.RadioButton
        Normalization_SelectDataTable   matlab.ui.control.Table
        Normalization_SamplePointSpinner  matlab.ui.control.Spinner
        SamplePointSpinnerLabel         matlab.ui.control.Label
        Normalization_SingleSpectrumButton  matlab.ui.control.RadioButton
        Normalization_AllSpectraButton  matlab.ui.control.RadioButton
        PeakBinningPanel                matlab.ui.container.Panel
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        EdgeListButton                  matlab.ui.control.Button
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        Normalization_MaxIntensityButton  matlab.ui.control.ToggleButton
        Normalization_ReferenceMZEditField  matlab.ui.control.NumericEditField
        ReferenceMZLabel                matlab.ui.control.Label
        Normalization_NoiseLevelButton  matlab.ui.control.ToggleButton
        Normalization_MedianButton      matlab.ui.control.ToggleButton
        Normalization_pnormButton       matlab.ui.control.ToggleButton
        Normalization_pvalueSpinner     matlab.ui.control.Spinner
        pvalueSpinnerLabel              matlab.ui.control.Label
        Normalization_ReferencePeakButton  matlab.ui.control.ToggleButton
        Normalization_ioncountsButton   matlab.ui.control.ToggleButton
        Preprocessing_ExportButton      matlab.ui.control.Button
        SectionofInterestPanel          matlab.ui.container.Panel
        Preprocessing_EndingpointEditField  matlab.ui.control.EditField
        EndingpointEditFieldLabel       matlab.ui.control.Label
        Preprocessing_StartingpointEditField  matlab.ui.control.EditField
        StartingpointEditFieldLabel     matlab.ui.control.Label
        PeakAlignmentPanel              matlab.ui.container.Panel
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MaximumshiftallowedEditField  matlab.ui.control.NumericEditField
        MinimumsegementsizeallowedLabel  matlab.ui.control.Label
        Preprocessing_MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ReferenceSpectrumEditField  matlab.ui.control.NumericEditField
        BaselineCorrectionPanel         matlab.ui.container.Panel
        QuantilevalueEditFieldLabel     matlab.ui.control.Label
        Preprocessing_QuantilevalueEditField  matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        Preprocessing_StepsizeEditField  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        Preprocessing_WindowSizeEditField  matlab.ui.control.NumericEditField
        PreProcessingToolLabel          matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        ClassificationResultsLabel      matlab.ui.control.Label
        VisualizationTab                matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ProjectInfoTab                  matlab.ui.container.Tab
        DocumentationButton             matlab.ui.control.Button
        ContactInformationTestsexymailcomLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AboutMSpecLabel                 matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        AboutYourProjectPanel           matlab.ui.container.Panel
        DataExportPanel                 matlab.ui.container.Panel
        StepsLabel_2                    matlab.ui.control.Label
        Export_PeakBinningCheckBox      matlab.ui.control.CheckBox
        ExportCSVFilesButton            matlab.ui.control.Button
        Export_PreprocessingCheckBox    matlab.ui.control.CheckBox
        Export_NormalizationCheckBox    matlab.ui.control.CheckBox
        Export_PeakDetectionCheckBox    matlab.ui.control.CheckBox
        ProjectInfo_CreatedDate         matlab.ui.control.EditField
        CreatedLabel                    matlab.ui.control.Label
        ReportGenerationPanel           matlab.ui.container.Panel
        StepsLabel                      matlab.ui.control.Label
        PeakBinningCheckBox             matlab.ui.control.CheckBox
        GeneratePDFReportButton         matlab.ui.control.Button
        PreprocessingCheckBox           matlab.ui.control.CheckBox
        NormalizationCheckBox           matlab.ui.control.CheckBox
        PeakDetectionCheckBox           matlab.ui.control.CheckBox
        EditButton                      matlab.ui.control.StateButton
        ProjectInfo_DescriptionTextArea  matlab.ui.control.TextArea
        DescriptionTextAreaLabel_2      matlab.ui.control.Label
        ProjectInfo_ImportedFileEditField  matlab.ui.control.EditField
        ImportedFileEditFieldLabel      matlab.ui.control.Label
        ProjectInfo_ProjectNameEditField  matlab.ui.control.EditField
        ProjectNameEditField_2Label     matlab.ui.control.Label
    end

    
    properties (Access = public)
        CurrentProject
        CurrentModel
        CurrentPreprocessParameters
        MdlImportApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MSpecAnalysisController.retrieveModelTypes(app);
        end

        % Selection changed function: ModelTypeTree
        function ModelTypeTreeSelectionChanged(app, event)
            selectedNodes = app.ModelTypeTree.SelectedNodes;
            if selectedNodes.Parent == app.ModelTypeTree
                % do nothing
                % to be added later
            else
                MSpecAnalysisController.retrieveModelInfo(app);
            end
        end

        % Button pushed function: ImportModelButton
        function ImportModelButtonPushed(app, event)
            app.MdlImportApp = ModelImportApp(app);
        end

        % Button pushed function: Import_ImportButton
        function Import_ImportButtonPushed(app, event)
            MSpecAnalysisController.importData(app);
        end

        % Button pushed function: Import_CreateProjectButton
        function Import_CreateProjectButtonPushed(app, event)
            MSpecAnalysisController.createProject (app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            MSpecAnalysisController.startPreprocessing(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSpecAnalysisUIFigure = uifigure('Visible', 'off');
            app.MSpecAnalysisUIFigure.Name = 'MSpec Analysis';
            app.MSpecAnalysisUIFigure.Icon = 'MSA_icon.png';
            app.Menu = uimenu(app.MSpecAnalysisUIFigure);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.MSpecAnalysisUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.MSpecAnalysisUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New Project'};
            app.PushTool.Icon = 'plus-red.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open Project'};
            app.PushTool3.Icon = 'Import.png';
            app.PushTool3.Separator = 'on';
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Icon = 'icons8-save-96.png';
            app.ToggleTool.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Icon = 'download-red.png';
            app.PushTool4.Separator = 'on';
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.Icon = 'report-red.png';
            app.PushTool7.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Icon = 'info-red.png';
            app.PushTool6.Separator = 'on';
            app.TabGroup = uitabgroup(app.MSpecAnalysisUIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.WelcometoMSpecLabel = uilabel(app.ImportTab);
            app.WelcometoMSpecLabel.Text = 'Welcome to MSpec !';
            app.ImportyourmassspectrometrydatatobeginLabel = uilabel(app.ImportTab);
            app.ImportyourmassspectrometrydatatobeginLabel.Text = 'Import your mass spectrometry data to begin :)';
            app.Import_ImportButton = uibutton(app.ImportTab, 'push');
            app.Import_ImportButton.ButtonPushedFcn = createCallbackFcn(app, @Import_ImportButtonPushed, true);
            app.Import_ImportButton.Text = 'Import';
            app.CreateNewProjectPanel = uipanel(app.ImportTab);
            app.CreateNewProjectPanel.Title = 'Create New Project';
            app.ProjectNameEditFieldLabel = uilabel(app.CreateNewProjectPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.CreateNewProjectPanel, 'text');
            app.DataTypesButtonGroup = uibuttongroup(app.CreateNewProjectPanel);
            app.DataTypesButtonGroup.Title = 'Data Types';
            app.MassSpectra1DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.MassSpectra1DButton.Text = 'Mass Spectra (1D)';
            app.MassSpectra1DButton.Value = true;
            app.Imaging2DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.Imaging2DButton.Text = 'Imaging (2D)';
            app.DimensionLabel = uilabel(app.CreateNewProjectPanel);
            app.DimensionLabel.Text = 'Dimension';
            app.WidthField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.WidthField.Editable = 'off';
            app.xLabel = uilabel(app.CreateNewProjectPanel);
            app.xLabel.Text = 'x';
            app.HeightField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.HeightField.Editable = 'off';
            app.Image = uiimage(app.CreateNewProjectPanel);
            app.Image.Tooltip = {'Dimension of your data. In case of imaging data, input " width x height ".'};
            app.Image.ImageSource = 'info_icon.png';
            app.NumberofMassSpectraEditFieldLabel = uilabel(app.CreateNewProjectPanel);
            app.NumberofMassSpectraEditFieldLabel.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.NumberofMassSpectraEditField.Editable = 'off';
            app.Import_CreateProjectButton = uibutton(app.CreateNewProjectPanel, 'push');
            app.Import_CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @Import_CreateProjectButtonPushed, true);
            app.Import_CreateProjectButton.Enable = 'off';
            app.Import_CreateProjectButton.Text = 'Create';
            app.DescriptionTextAreaLabel = uilabel(app.CreateNewProjectPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionEditField = uitextarea(app.CreateNewProjectPanel);
            app.RecentFilesPanel = uipanel(app.ImportTab);
            app.RecentFilesPanel.Title = 'Recent Files';
            app.RecentFileListBox = uilistbox(app.RecentFilesPanel);
            app.OpenButton = uibutton(app.RecentFilesPanel, 'push');
            app.OpenButton.Text = 'Open';
            app.ImportStatusLabel = uilabel(app.ImportTab);
            app.ImportStatusLabel.Text = '';
            app.Image2 = uiimage(app.ImportTab);
            app.Image2.ImageSource = 'MspecAnalysis_logo.png';
            app.AnalysisLabel = uilabel(app.ImportTab);
            app.AnalysisLabel.Text = 'Analysis';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.ClassificationLabel = uilabel(app.ClassificationTab);
            app.ClassificationLabel.Text = 'Classification';
            app.ModelTypeTree = uitree(app.ClassificationTab);
            app.ModelTypeTree.SelectionChangedFcn = createCallbackFcn(app, @ModelTypeTreeSelectionChanged, true);
            app.KNEARESTNEIGHBORKNNNode = uitreenode(app.ModelTypeTree);
            app.KNEARESTNEIGHBORKNNNode.Text = 'K-NEAREST NEIGHBOR (KNN)';
            app.SUPPORTVECTORMACHINESSVMNode = uitreenode(app.ModelTypeTree);
            app.SUPPORTVECTORMACHINESSVMNode.Text = 'SUPPORT VECTOR MACHINES (SVM)';
            app.DECISIONTREESNode = uitreenode(app.ModelTypeTree);
            app.DECISIONTREESNode.Text = 'DECISION TREES';
            app.NAIVEBAYESCLASSIFIERSNode = uitreenode(app.ModelTypeTree);
            app.NAIVEBAYESCLASSIFIERSNode.Text = 'NAIVE BAYES CLASSIFIERS';
            app.ModelTypeLabel = uilabel(app.ClassificationTab);
            app.ModelTypeLabel.Text = 'Model Type';
            app.ImportModelButton = uibutton(app.ClassificationTab, 'push');
            app.ImportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelButtonPushed, true);
            app.ImportModelButton.Text = 'Import Model';
            app.ModelInformationPanel = uipanel(app.ClassificationTab);
            app.ModelInformationPanel.Title = 'Model Information';
            app.ModelInfoTextArea = uitextarea(app.ModelInformationPanel);
            app.ModelInfoTextArea.Editable = 'off';
            app.ImportedDataPanel = uipanel(app.ClassificationTab);
            app.ImportedDataPanel.Title = 'Imported Data';
            app.NumberofMassSpectraEditField_2Label = uilabel(app.ImportedDataPanel);
            app.NumberofMassSpectraEditField_2Label.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField_2 = uieditfield(app.ImportedDataPanel, 'numeric');
            app.NumberofMassSpectraEditField_2.Editable = 'off';
            app.DimensionLabel_2 = uilabel(app.ImportedDataPanel);
            app.DimensionLabel_2.Text = 'Dimension';
            app.WidthField_2 = uieditfield(app.ImportedDataPanel, 'numeric');
            app.WidthField_2.Editable = 'off';
            app.xLabel_2 = uilabel(app.ImportedDataPanel);
            app.xLabel_2.Text = 'x';
            app.HeightField_2 = uieditfield(app.ImportedDataPanel, 'numeric');
            app.HeightField_2.Editable = 'off';
            app.Image_2 = uiimage(app.ImportedDataPanel);
            app.Image_2.Tooltip = {'Dimension of your data. In case of imaging data, input " width x height ".'};
            app.Image_2.ImageSource = 'info_icon.png';
            app.DataTypeButtonGroup = uibuttongroup(app.ImportedDataPanel);
            app.MassSpectra1DButton_2 = uiradiobutton(app.DataTypeButtonGroup);
            app.MassSpectra1DButton_2.Text = 'Mass Spectra (1D)';
            app.MassSpectra1DButton_2.Value = true;
            app.Imaging2DButton_2 = uiradiobutton(app.DataTypeButtonGroup);
            app.Imaging2DButton_2.Text = 'Imaging (2D)';
            app.DataTypeLabel = uilabel(app.ImportedDataPanel);
            app.DataTypeLabel.Text = 'Data Type';
            app.ImportedDataTable = uitable(app.ImportedDataPanel);
            app.ImportedDataTable.ColumnName = '';
            app.ImportedDataTable.RowName = {};
            app.StartButton = uibutton(app.ClassificationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.UIAxes3 = uiaxes(app.PreprocessingTab);
            title(app.UIAxes3, 'Preprocessed Data')
            xlabel(app.UIAxes3, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes3, 'Relative Intensity')
            zlabel(app.UIAxes3, 'Z')
            app.PreProcessingToolLabel = uilabel(app.PreprocessingTab);
            app.PreProcessingToolLabel.Text = 'Pre-Processing Tool';
            app.BaselineCorrectionPanel = uipanel(app.PreprocessingTab);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.Preprocessing_WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_WindowSizeEditField.Editable = 'off';
            app.WindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.Preprocessing_StepsizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_StepsizeEditField.Editable = 'off';
            app.StepsizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.Preprocessing_QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_QuantilevalueEditField.Limits = [0 Inf];
            app.Preprocessing_QuantilevalueEditField.Editable = 'off';
            app.QuantilevalueEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.QuantilevalueEditFieldLabel.Text = 'Quantile value';
            app.PeakAlignmentPanel = uipanel(app.PreprocessingTab);
            app.PeakAlignmentPanel.Title = 'Peak Alignment';
            app.Preprocessing_ReferenceSpectrumEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_ReferenceSpectrumEditField.Editable = 'off';
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.Preprocessing_MinimumsegementsizeallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MinimumsegementsizeallowedEditField.Editable = 'off';
            app.MinimumsegementsizeallowedLabel = uilabel(app.PeakAlignmentPanel);
            app.MinimumsegementsizeallowedLabel.Text = {'Minimum segement'; 'size allowed'};
            app.Preprocessing_MaximumshiftallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MaximumshiftallowedEditField.Editable = 'off';
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.SectionofInterestPanel = uipanel(app.PreprocessingTab);
            app.SectionofInterestPanel.Title = 'Section of Interest';
            app.StartingpointEditFieldLabel = uilabel(app.SectionofInterestPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.Preprocessing_StartingpointEditField = uieditfield(app.SectionofInterestPanel, 'text');
            app.Preprocessing_StartingpointEditField.Editable = 'off';
            app.EndingpointEditFieldLabel = uilabel(app.SectionofInterestPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.Preprocessing_EndingpointEditField = uieditfield(app.SectionofInterestPanel, 'text');
            app.Preprocessing_EndingpointEditField.Editable = 'off';
            app.Preprocessing_ExportButton = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_ExportButton.Text = 'Export';
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.PreprocessingTab);
            app.NormalizationMethodsButtonGroup.Enable = 'off';
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.Normalization_ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ioncountsButton.Text = 'ion counts';
            app.Normalization_ioncountsButton.Value = true;
            app.Normalization_ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ReferencePeakButton.Text = 'Reference Peak';
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.Normalization_pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.Normalization_pvalueSpinner.Limits = [1 Inf];
            app.Normalization_pvalueSpinner.Value = 1;
            app.Normalization_pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_pnormButton.Text = 'p-norm';
            app.Normalization_MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MedianButton.Text = 'Median';
            app.Normalization_NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_NoiseLevelButton.Text = 'Noise Level';
            app.ReferenceMZLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.ReferenceMZLabel.Text = {'Reference'; 'M/Z'};
            app.Normalization_ReferenceMZEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.Normalization_ReferenceMZEditField.ValueDisplayFormat = '%5.4f';
            app.Normalization_ReferenceMZEditField.Editable = 'off';
            app.Normalization_MaxIntensityButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MaxIntensityButton.Text = 'Maximum Intensity';
            app.PeakBinningPanel = uipanel(app.PreprocessingTab);
            app.PeakBinningPanel.Title = 'Peak Binning';
            app.EdgeListButton = uibutton(app.PeakBinningPanel, 'push');
            app.EdgeListButton.Text = 'Edge List';
            app.NumberofBinsEditFieldLabel = uilabel(app.PeakBinningPanel);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.PeakBinningPanel, 'numeric');
            app.ViewOptionButtonGroup = uibuttongroup(app.PreprocessingTab);
            app.ViewOptionButtonGroup.Title = ' View Option';
            app.Normalization_AllSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_AllSpectraButton.Text = 'All Spectra';
            app.Normalization_SingleSpectrumButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_SingleSpectrumButton.Text = 'Single Spectrum';
            app.Normalization_SingleSpectrumButton.Value = true;
            app.SamplePointSpinnerLabel = uilabel(app.ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel.Text = 'Sample Point';
            app.Normalization_SamplePointSpinner = uispinner(app.ViewOptionButtonGroup);
            app.Normalization_SamplePointSpinner.Limits = [1 80];
            app.Normalization_SamplePointSpinner.Value = 1;
            app.Normalization_SelectDataTable = uitable(app.ViewOptionButtonGroup);
            app.Normalization_SelectDataTable.ColumnName = {'Sample Point'; 'Display'};
            app.Normalization_SelectDataTable.RowName = {};
            app.Normalization_SelectDataTable.ColumnSortable = true;
            app.Normalization_SelectDataTable.ColumnEditable = [false true];
            app.Normalization_SelectDataTable.Enable = 'off';
            app.Normalization_MultipleSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_MultipleSpectraButton.Text = 'Multiple Spectra';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ClassificationResultsLabel = uilabel(app.ResultsTab);
            app.ClassificationResultsLabel.Text = 'Classification Results';
            app.UITable2 = uitable(app.ResultsTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.UIAxes4 = uiaxes(app.VisualizationTab);
            title(app.UIAxes4, 'Imaging')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTickLabel = '';
            app.UIAxes5 = uiaxes(app.VisualizationTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.ProjectInfoTab = uitab(app.TabGroup);
            app.ProjectInfoTab.Title = 'Project Info';
            app.AboutYourProjectPanel = uipanel(app.ProjectInfoTab);
            app.AboutYourProjectPanel.Title = 'About Your Project';
            app.ProjectNameEditField_2Label = uilabel(app.AboutYourProjectPanel);
            app.ProjectNameEditField_2Label.Text = 'Project Name';
            app.ProjectInfo_ProjectNameEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ProjectNameEditField.Editable = 'off';
            app.ImportedFileEditFieldLabel = uilabel(app.AboutYourProjectPanel);
            app.ImportedFileEditFieldLabel.Text = 'Imported File';
            app.ProjectInfo_ImportedFileEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ImportedFileEditField.Editable = 'off';
            app.DescriptionTextAreaLabel_2 = uilabel(app.AboutYourProjectPanel);
            app.DescriptionTextAreaLabel_2.Text = 'Description';
            app.ProjectInfo_DescriptionTextArea = uitextarea(app.AboutYourProjectPanel);
            app.ProjectInfo_DescriptionTextArea.Editable = 'off';
            app.EditButton = uibutton(app.AboutYourProjectPanel, 'state');
            app.EditButton.Text = 'Edit';
            app.ReportGenerationPanel = uipanel(app.AboutYourProjectPanel);
            app.ReportGenerationPanel.Title = 'Report Generation';
            app.PeakDetectionCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PeakDetectionCheckBox.Text = 'Peak Detection';
            app.PeakDetectionCheckBox.Value = true;
            app.NormalizationCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.NormalizationCheckBox.Text = 'Normalization';
            app.NormalizationCheckBox.Value = true;
            app.PreprocessingCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PreprocessingCheckBox.Text = 'Pre-processing';
            app.PreprocessingCheckBox.Value = true;
            app.GeneratePDFReportButton = uibutton(app.ReportGenerationPanel, 'push');
            app.GeneratePDFReportButton.Text = 'Generate PDF Report';
            app.PeakBinningCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PeakBinningCheckBox.Text = 'Peak Binning';
            app.PeakBinningCheckBox.Value = true;
            app.StepsLabel = uilabel(app.ReportGenerationPanel);
            app.StepsLabel.Text = 'Steps';
            app.CreatedLabel = uilabel(app.AboutYourProjectPanel);
            app.CreatedLabel.Text = 'Created';
            app.ProjectInfo_CreatedDate = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_CreatedDate.Editable = 'off';
            app.DataExportPanel = uipanel(app.AboutYourProjectPanel);
            app.DataExportPanel.Title = 'Data Export';
            app.Export_PeakDetectionCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PeakDetectionCheckBox.Text = 'Peak Detection';
            app.Export_PeakDetectionCheckBox.Value = true;
            app.Export_NormalizationCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_NormalizationCheckBox.Text = 'Normalization';
            app.Export_NormalizationCheckBox.Value = true;
            app.Export_PreprocessingCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PreprocessingCheckBox.Text = 'Pre-processing';
            app.Export_PreprocessingCheckBox.Value = true;
            app.ExportCSVFilesButton = uibutton(app.DataExportPanel, 'push');
            app.ExportCSVFilesButton.Text = 'Export CSV Files';
            app.Export_PeakBinningCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PeakBinningCheckBox.Text = 'Peak Binning';
            app.Export_PeakBinningCheckBox.Value = true;
            app.StepsLabel_2 = uilabel(app.DataExportPanel);
            app.StepsLabel_2.Text = 'Steps';
            app.Image2_2 = uiimage(app.ProjectInfoTab);
            app.Image2_2.ImageSource = 'Mspec_logo.png';
            app.AboutMSpecLabel = uilabel(app.ProjectInfoTab);
            app.AboutMSpecLabel.Text = 'About MSpec';
            app.Label = uilabel(app.ProjectInfoTab);
            app.Label.Text = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit.'; 'Vivamus sit amet purus porta, congue orci quis, consequat sem.'; 'Mauris luctus eros eu velit vestibulum, quis venenatis eros congue.'; 'Donec gravida sollicitudin elit ac tristique.'; 'Nam consequat turpis in mollis suscipit.'; 'Curabitur varius feugiat lorem id blandit.'; 'Nunc rutrum nisl et nunc porttitor, vel porttitor neque semper.'; 'Sed placerat accumsan urna, in ornare ligula dignissim eget.'; 'Quisque auctor tortor ut ligula volutpat fermentum.'; 'Nam magna eros, congue eu ornare non, semper vitae felis.'; 'Duis ex augue, mollis ut nulla ut, tincidunt euismod purus.'; 'Sed ultrices leo id felis sagittis finibus.'};
            app.ContactInformationTestsexymailcomLabel = uilabel(app.ProjectInfoTab);
            app.ContactInformationTestsexymailcomLabel.Text = 'Contact Information: Test@sexymail.com';
            app.DocumentationButton = uibutton(app.ProjectInfoTab, 'push');
            app.DocumentationButton.Text = 'Documentation';
            % Show the figure after all components are created
            app.MSpecAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSpecAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSpecAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSpecAnalysisUIFigure)
        end
    end
end