classdef MSpecMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSPECAppUIFigure               matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        NewProjectMenu                 matlab.ui.container.Menu
        OpenProjectMenu                matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        WelcometoMSpecLabel            matlab.ui.control.Label
        ImportyourmassspectrometrydatatobeginLabel  matlab.ui.control.Label
        Import_ImportButton            matlab.ui.control.Button
        CreateNewProjectPanel          matlab.ui.container.Panel
        ProjectNameEditFieldLabel      matlab.ui.control.Label
        ProjectNameEditField           matlab.ui.control.EditField
        DataTypesButtonGroup           matlab.ui.container.ButtonGroup
        MassSpectra1DButton            matlab.ui.control.RadioButton
        Imaging2DButton                matlab.ui.control.RadioButton
        DimensionLabel                 matlab.ui.control.Label
        WidthField                     matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        HeightField                    matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        NumberofMassSpectraLabel       matlab.ui.control.Label
        NumberofMassSpectraEditField   matlab.ui.control.NumericEditField
        Import_CreateProjectButton     matlab.ui.control.Button
        RecentFilesPanel               matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        OpenButton                     matlab.ui.control.Button
        ImportStatusLabel              matlab.ui.control.Label
        PreprocessingTab               matlab.ui.container.Tab
        PreProcessingToolLabel         matlab.ui.control.Label
        BaselineCorrectionPanel        matlab.ui.container.Panel
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        Preprocessing_WindowSizeEditField  matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        Preprocessing_StepsizeEditField  matlab.ui.control.NumericEditField
        QuantilevalueEditFieldLabel    matlab.ui.control.Label
        Preprocessing_QuantilevalueEditField  matlab.ui.control.NumericEditField
        PeakAlignmentPanel             matlab.ui.container.Panel
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ReferenceSpectrumEditField  matlab.ui.control.NumericEditField
        MinimumsegementsizeallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MaximumshiftallowedEditField  matlab.ui.control.NumericEditField
        PlotPanel                      matlab.ui.container.Panel
        SpectrumtodisplayEditFieldLabel  matlab.ui.control.Label
        Preprocessing_SpectrumtodisplayEditField  matlab.ui.control.EditField
        Preprocessing_SectionofinterestLabel  matlab.ui.control.Label
        StartingpointEditFieldLabel    matlab.ui.control.Label
        Preprocessing_StartingpointEditField  matlab.ui.control.EditField
        EndingpointEditFieldLabel      matlab.ui.control.Label
        Preprocessing_EndingpointEditField  matlab.ui.control.EditField
        Preprocessing_PlotButton       matlab.ui.control.Button
        Preprocessing_ExportButton     matlab.ui.control.Button
        Preprocessing_SaveButton       matlab.ui.control.Button
        Preprocessing_PreprocessedMSPlot  matlab.ui.control.UIAxes
        Preprocessing_RawMSPlot        matlab.ui.control.UIAxes
        DataNormalizationTab           matlab.ui.container.Tab
        RawDataPlot                    matlab.ui.control.UIAxes
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        ioncountsButton                matlab.ui.control.ToggleButton
        ReferencePeakButton            matlab.ui.control.ToggleButton
        PeakSpinnerLabel               matlab.ui.control.Label
        PeakSpinner                    matlab.ui.control.Spinner
        pvalueSpinnerLabel             matlab.ui.control.Label
        pvalueSpinner                  matlab.ui.control.Spinner
        ExclusionofmassrangesCheckBox  matlab.ui.control.CheckBox
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.NumericEditField
        UpperEditFieldLabel            matlab.ui.control.Label
        UpperEditField                 matlab.ui.control.NumericEditField
        NowSelectLabel                 matlab.ui.control.Label
        pnormButton                    matlab.ui.control.ToggleButton
        MedianButton                   matlab.ui.control.ToggleButton
        NoiseLevelButton               matlab.ui.control.ToggleButton
        NormalizedDataPlot             matlab.ui.control.UIAxes
        ViewOptionButtonGroup          matlab.ui.container.ButtonGroup
        AllSpectraButton               matlab.ui.control.RadioButton
        SingleSpectrumButton           matlab.ui.control.RadioButton
        SamplePointSpinnerLabel        matlab.ui.control.Label
        SamplePointSpinner             matlab.ui.control.Spinner
        SelectDataTable                matlab.ui.control.Table
        MultipleSpectraButton          matlab.ui.control.RadioButton
        ExportFilesPanel               matlab.ui.container.Panel
        GeneratePDFReportButton        matlab.ui.control.Button
        ExportCSVNormalizedDataButton  matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        PeakDetectionBinningTab        matlab.ui.container.Tab
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_7                       matlab.ui.control.UIAxes
        PeakDetectionPanel             matlab.ui.container.Panel
        BASEEditFieldLabel             matlab.ui.control.Label
        BASEEditField                  matlab.ui.control.NumericEditField
        HEIGHTFILTEREditFieldLabel     matlab.ui.control.Label
        HEIGHTFILTEREditField          matlab.ui.control.NumericEditField
        MULTIPLIEREditFieldLabel       matlab.ui.control.Label
        MULTIPLIEREditField            matlab.ui.control.NumericEditField
        PeakThresholdEditFieldLabel    matlab.ui.control.Label
        PeakThresholdEditField         matlab.ui.control.NumericEditField
        SpectrumtodisplayEditField_2Label  matlab.ui.control.Label
        SpectrumtodisplayEditField_2   matlab.ui.control.EditField
        BiningPanel                    matlab.ui.container.Panel
        CUTOFFEditFieldLabel           matlab.ui.control.Label
        CUTOFFEditField                matlab.ui.control.NumericEditField
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        CriterionDropDownLabel         matlab.ui.control.Label
        CriterionDropDown              matlab.ui.control.DropDown
        SpectrumtodisplayEditField_3Label  matlab.ui.control.Label
        SpectrumtodisplayEditField_3   matlab.ui.control.EditField
        UITable_2                      matlab.ui.control.Table
        ExportCSVButton                matlab.ui.control.Button
        PeakDetectionandPeakBinningLabel  matlab.ui.control.Label
        ImagingTab                     matlab.ui.container.Tab
        Imaging_NormalizedDataPlot     matlab.ui.control.UIAxes
        FeaturePeakLabel               matlab.ui.control.Label
        FeaturePeakSlider              matlab.ui.control.Slider
        SelectedPeakLabel              matlab.ui.control.Label
        Imaging_Display                matlab.ui.control.UIAxes
        ColorMapDropDownLabel          matlab.ui.control.Label
        ColorMapDropDown               matlab.ui.control.DropDown
        Imaging_SelectedDataPlot       matlab.ui.control.UIAxes
        ColorScaleOptionListBoxLabel   matlab.ui.control.Label
        ColorScaleOptionListBox        matlab.ui.control.ListBox
        AnalyticsTab                   matlab.ui.container.Tab
        SelectClassTable               matlab.ui.control.Table
        Imaging_Display_2              matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ProjectInfoTab                 matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
    end

    
    properties (Access = public)
        
        CurrentProject MSProject; %current project
        
        DataArray % Our imported data
        XAxisPlot % Our x-axis data
        RowNumber
        ColumnNumber
        NormalizeMethod = 'Sum'; % default normalize method
        NormalizeDisplay = 'All';
        NormalizedArray
        ReferencePeak
        FeaturePeak = 1;
        FileName = '';
        NormalizationNormValue = 1;
        RegressionDegree = 1;
        ImageArray;
    end
    
    methods (Access = private)
        
        function updateNormalizedArray(app)
            % Data Normalization
                
            app.NormalizedArray = app.DataArray;

            switch app.NormalizeMethod % Get Tag of selected object.
                case 'Sum'
                    for j = 1:app.ColumnNumber
                        colj = app.NormalizedArray(:,j);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./sum(colj);
                    end
                 case 'Area'
                    for j = 1:app.ColumnNumber
                        colj = app.NormalizedArray(:,j);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./trapz(app.XAxisPlot, colj);
                    end
                 case 'Norm'
                    for j = 1:app.ColumnNumber
                        factor = norm(app.NormalizedArray(:,j),app.NormalizationNormValue);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./factor;
                    end
                 case 'Median'
                     for j = 1:app.ColumnNumber
                        factor = median(app.NormalizedArray(:,j));
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./factor;
                     end
                 case 'Noise'
                    for j = 1:app.ColumnNumber
                        % Noise Level
                        DifVector = diff(app.NormalizedArray(:,j));
                        % universal thresholding
                        MedOfDif = median(DifVector);
                        e = abs(DifVector-MedOfDif);
                        factor = median(e);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./factor;
                    end
                 otherwise %peak
                    for j = 1:app.ColumnNumber
                        ref = app.NormalizedArray(app.ReferencePeak,j);
                        app.NormalizedArray(:, j) = app.NormalizedArray(:, j)./ref;
                    end
           end
                
           setDefaultFeaturePeak(app);
           plotNormalizedData(app);
           DisplayDataTable(app);
           
        end
        
        function setDefaultFeaturePeak(app)
            SDNorm = std(app.NormalizedArray,0,2); % dim = 2 means for each row
            [Max,IndexMax] = max(SDNorm);
            app.FeaturePeak = IndexMax;
            app.FeaturePeakSlider.Value = double(IndexMax);
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
        end
        
        function setDefaultReferencePeak(app)
            SD = std(app.DataArray,0,2); % dim = 2 means for each row
            [Max,IndexMax] = max(SD);
            app.ReferencePeak = IndexMax;
            app.PeakSpinner.Value = double(IndexMax);
        end
        
        
        function plotNormalizedData(app)
            cla(app.NormalizedDataPlot)
            % Plotting Normalized Data
            
            switch app.NormalizeDisplay
                case 'All'
                    hold(app.NormalizedDataPlot,"on");
                    for k = 1:app.ColumnNumber
                        stem(app.NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                    end
                    hold(app.NormalizedDataPlot,"off");
                case 'Single'
                    bar(app.NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, app.SamplePointSpinner.Value));
                case 'Multiple'
                    retreivedata = get(app.SelectDataTable,'data');
                    hold(app.NormalizedDataPlot,"on");
                    for k = 1:length(retreivedata(:,2))
                        if retreivedata(k,2) == 1
                            stem(app.NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                        end
                    end
                    hold(app.NormalizedDataPlot,"off");
            end     
            Image_plotNormalizedData(app);
            Imaging_PlotImage(app);
        end
        
        function Image_plotNormalizedData(app)
            cla(app.Imaging_NormalizedDataPlot)
            % Plotting Normalized Data
            
            switch app.NormalizeDisplay
                case 'All'
                    hold(app.Imaging_NormalizedDataPlot,"on");
                    for k = 1:app.ColumnNumber
                        stem(app.Imaging_NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                    end
                    xline(app.Imaging_NormalizedDataPlot, app.FeaturePeak,'-.r',{'Selected','Peak'});
                    hold(app.Imaging_NormalizedDataPlot,"off");
                case 'Single'
                    bar(app.Imaging_NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, app.SamplePointSpinner.Value));
                    xline(app.Imaging_NormalizedDataPlot, app.FeaturePeak,'-.r',{'Selected','Peak'});
                case 'Multiple'
                    retreivedata = get(app.SelectDataTable,'data');
                    hold(app.Imaging_NormalizedDataPlot,"on");
                    for k = 1:length(retreivedata(:,2))
                        if retreivedata(k,2) == 1
                            stem(app.Imaging_NormalizedDataPlot, app.XAxisPlot, app.NormalizedArray(:, k))
                        end
                    end
                    xline(app.Imaging_NormalizedDataPlot, app.FeaturePeak,'-.r',{'Selected','Peak'});
                    hold(app.Imaging_NormalizedDataPlot,"off");
            end
        end
        
        function DisplayDataTable(app)
            NormData = transpose(app.NormalizedArray);
            %SampleIndex = transpose(1:app.ColumnNumber);
            %DataToDisplay = cat(2,SampleIndex,NormData);
            app.UITable.RowName = 'numbered';
            app.UITable.ColumnName = 'numbered';

            app.UITable.Data = NormData;
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s);
        end
        
        function DisplaySamplePointOption(app)
            SampleIndex = transpose(1:app.ColumnNumber);
            F = false(app.ColumnNumber,1);
            F = [SampleIndex F];
            app.SelectDataTable.Data = F;
            app.SelectDataTable.ColumnFormat = {'char', 'logical'};
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.SelectDataTable,s);
        end
        
        function Imaging_PlotImage(app)
            FeatureRow = app.NormalizedArray(app.FeaturePeak, :);
            app.ImageArray = transpose(reshape(FeatureRow,9,9,[]));
            maxy = max(app.NormalizedArray,[],'all');
            imaging = imagesc(app.Imaging_Display,app.ImageArray);
            colorbar(app.Imaging_Display);
            colormap(app.Imaging_Display,app.ColorMapDropDown.Value);
            set(imaging, 'ButtonDownFcn', {@ImageClickCallback});
            
                function ImageClickCallback ( objectHandle , eventData )
                    temp = app.Imaging_Display.CurrentPoint;
                    temp = temp(1,1:2);
                    x = round(temp(1));
                    y = round(temp(2));
                    Imaging_PlotSpectrum(app,x,y,maxy);
                end
  
        end
        function Imaging_PlotSpectrum(app,xcoordinate,ycoordinate,ylimit)
            index = ((ycoordinate-1)*9)+xcoordinate;
            barchart = bar(app.Imaging_SelectedDataPlot, app.XAxisPlot, app.NormalizedArray(:, index));
            barchart.FaceColor = 'flat';
            barchart.CData(app.FeaturePeak,:) = [1 0 0];
            ylim(app.Imaging_SelectedDataPlot,[0, ylimit]);
        end
        
        function DEMO_DisplayClassOption(app)
            SampleIndex = transpose(["class1","class2","class3"]);
            F = false(3,1);
            F = [SampleIndex F];
            app.SelectClassTable.Data = F;
            app.SelectClassTable.ColumnFormat = {'char', 'logical'};
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.SelectClassTable,s);
            DEMO_Analysis_Image(app);
            DEMO_PlotClassHistogram(app);
        end
        
        function DEMO_Analysis_Image(app)
            %test
            C = zeros(9,9,3);
            retreivedata = get(app.SelectClassTable,'data');
            disp(retreivedata);
            if retreivedata(1,2)=="1"
                img1 = rescale(reshape(app.NormalizedArray(12, :),9,9,[]));
                C(:,:,1) = img1;
                disp("hello");
            end
            if retreivedata(2,2)=="1"
                img2 = rescale(reshape(app.NormalizedArray(29, :),9,9,[]));
                C(:,:,2) = img2;
            end
            if retreivedata(3,2)=="1"
                img3 = rescale(reshape(app.NormalizedArray(40, :),9,9,[]));
                C(:,:,3) = img3;
            end
            imaging = imagesc(app.Imaging_Display_2,C);
            colorbar(app.Imaging_Display_2);
            
            set(imaging, 'ButtonDownFcn', {@ImageClickCallback});
            
                function ImageClickCallback ( objectHandle , eventData )
                    temp = app.Imaging_Display_2.CurrentPoint;
                    temp = temp(1,1:2);
                    x = round(temp(1));
                    y = round(temp(2));
                    DEMO_Imaging_PlotSpectrum(app,x,y);
                end
        end
        function DEMO_Imaging_PlotSpectrum(app,xcoordinate,ycoordinate)
            index = ((ycoordinate-1)*9)+xcoordinate;
            barchart = bar(app.UIAxes2, app.XAxisPlot, app.NormalizedArray(:, index));
            barchart.FaceColor = 'flat';
            barchart.CData(12,:) = [1 0 0];
            barchart.CData(29,:) = [0 1 0];
            barchart.CData(40,:) = [0 0 1];

        end
        function DEMO_PlotClassHistogram(app)
            class1Data = app.NormalizedArray(12, :);
            class2Data = app.NormalizedArray(29, :);
            class3Data = app.NormalizedArray(40, :);
            histogram(app.UIAxes,class1Data);
            histogram(app.UIAxes_2,class2Data);
            histogram(app.UIAxes_3,class3Data);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FeaturePeakSlider.MajorTicks = (1:5:76);
            app.FeaturePeakSlider.MinorTicks = (1:76);
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
            app.PeakSpinner.Enable = false;
            app.pvalueSpinner.Enable = false;
            app.SamplePointSpinner.Enable = false;
                        
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            %if isNull(app.CurrentProject)
                %app.TabGroup.SelectedTab = app.ImportTab;
            %end
            %[file, path] = uigetfile('*.csv');
            %if isequal(file,0)
                %msgbox('Please input CSV files')
            %else
                %app.FileName = file;
                %app.DataArray = csvread(fullfile(path,file));
                % Data Array Preprocess and Properties
                % column 1 is used for plotting the x-axis
                %app.XAxisPlot = app.DataArray(:, 1);
                % removing the first column from the Array
                %app.DataArray(:,1) = [];
                % removing last col ( all 0s )
                % app.DataArray(:,81) = [];
                
                %plotting the raw data
                %[app.RowNumber,app.ColumnNumber]= size(app.DataArray);
                %hold(app.RawDataPlot,"on");
                %for i = 1:app.ColumnNumber
                    %stem(app.RawDataPlot,app.XAxisPlot,app.DataArray(:, i));
                %end
                %hold(app.RawDataPlot,"off");
                
                %updateNormalizedArray(app);
                %setDefaultReferencePeak(app);
                %DisplaySamplePointOption(app);
                %DEMO_DisplayClassOption(app);
            %end
        end

        % Selection changed function: 
        % NormalizationMethodsButtonGroup
        function NormalizationMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalizationMethodsButtonGroup.SelectedObject;
            app.PeakSpinner.Enable = false;
            app.pvalueSpinner.Enable = false;
            switch selectedButton % Get Tag of selected object.
                case app.ioncountsButton
                    app.NormalizeMethod='Sum';
                case app.pnormButton
                    app.NormalizeMethod='Norm';
                    app.pvalueSpinner.Enable = true;
                case app.MedianButton
                    app.NormalizeMethod='Median';
                case app.NoiseLevelButton
                    app.NormalizeMethod='Noise';
                otherwise
                    app.NormalizeMethod='Peak';
                    app.PeakSpinner.Enable = true;
            end
            app.NowSelectLabel.Text = app.NormalizeMethod;
            updateNormalizedArray(app);
        end

        % Value changed function: FeaturePeakSlider
        function FeaturePeakSliderValueChanged(app, event)
            value = app.FeaturePeakSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MinorTicks(minIdx); 
            app.FeaturePeak = value;
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
            plotNormalizedData(app);
        end

        % Value changed function: PeakSpinner
        function PeakSpinnerValueChanged(app, event)
            value = app.PeakSpinner.Value;
            app.ReferencePeak = value;
            updateNormalizedArray(app);
        end

        % Button pushed function: GeneratePDFReportButton
        function GeneratePDFReportButtonPushed(app, event)
            
           
            % Import report API classes (optional)
            import mlreportgen.report.*
            
            % Add report container (required)
            rpt = Report('output','pdf');
            
            % Add content to container (required)
            % Types of content added here: title 
            % page and table of contents reporters
            titlepg = TitlePage;
            titlepg.Title = 'MSPEC Report';
            titlepg.Author = sprintf('From: %s',app.FileName);
            add(rpt,titlepg);
            
            % Add content to report sections (optional)
            % Text and formal image added to chapter
            chap1 = Chapter('Normalization');
            add(chap1,['Method: ',app.NormalizeMethod]);
            
            f1 = figure('visible','off');
            %plotting the raw data
            subplot(2,1,1,"Units","pixels");
            hold on;
            for i = 1:app.ColumnNumber
                plot(app.XAxisPlot,app.DataArray(:, i));
            end
            hold off;
            title('Raw Data');
            % Normalized
            subplot(2,1,2,"Units","pixels");
            hold on;
            for k = 1:app.ColumnNumber
               plot(app.XAxisPlot, app.NormalizedArray(:, k))
            end
            %xline(app.FeaturePeak,'-.r',{'Selected','Peak'});
            hold off;
            title('Normalized Data');
            
            add(chap1,Figure(f1));
            add(rpt,chap1);
            
            
            % Close the report (required)
            close(rpt);
            % Display the report (optional)
            rptview(rpt);
        end

        % Button pushed function: ExportCSVNormalizedDataButton
        function ExportCSVNormalizedDataButtonPushed(app, event)
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            Arr = transpose(0:app.RowNumber-1);
            OutputArray = [Arr app.NormalizedArray];
            csvwrite(filename,OutputArray);
        end

        % Value changed function: pvalueSpinner
        function pvalueSpinnerValueChanged(app, event)
            value = app.pvalueSpinner.Value;
            app.NormalizationNormValue = value;
            updateNormalizedArray(app);
        end

        % Callback function
        function DegreeSpinnerValueChanged(app, event)
            value = app.DegreeSpinner.Value;
            app.RegressionDegree = value;
        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            app.SamplePointSpinner.Enable = false;
            app.SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.AllSpectraButton
                    app.NormalizeDisplay='All';
                case app.SingleSpectrumButton
                    app.NormalizeDisplay='Single';
                    app.SamplePointSpinner.Enable = true;
                otherwise
                    app.NormalizeDisplay='Multiple';
                    app.SelectDataTable.Enable = 'on';
            end
            plotNormalizedData(app);
        end

        % Value changed function: SamplePointSpinner
        function SamplePointSpinnerValueChanged(app, event)
            plotNormalizedData(app);
        end

        % Cell edit callback: SelectDataTable
        function SelectDataTableCellEdit(app, event)
            plotNormalizedData(app);
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            colormap(app.Imaging_Display,app.ColorMapDropDown.Value);
        end

        % Cell edit callback: SelectClassTable
        function SelectClassTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            DEMO_Analysis_Image(app);
        end

        % Button pushed function: Import_ImportButton
        function ImportButton_Pushed(app, event)
            DataImportExport.importData(app);
        end

        % Button pushed function: Import_CreateProjectButton
        function Import_CreateProjectButtonPushed(app, event)
            DataImportExport.createProject(app);
            app.SaveMenu.Enable = 'on';
        end

        % Button pushed function: Preprocessing_PlotButton
        function Preprocessing_PlotButtonPushed(app, event)
            MSpecController.plotButtonPushedHandler(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSPECAppUIFigure = uifigure('Visible', 'off');
            app.MSPECAppUIFigure.Name = 'MSPEC App';
            app.Menu = uimenu(app.MSPECAppUIFigure);
            app.Menu.Text = 'Menu';
            app.NewProjectMenu = uimenu(app.Menu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.Menu);
            app.OpenProjectMenu.Text = 'Open Project';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.MSPECAppUIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.WelcometoMSpecLabel = uilabel(app.ImportTab);
            app.WelcometoMSpecLabel.Text = 'Welcome to MSpec !';
            app.ImportyourmassspectrometrydatatobeginLabel = uilabel(app.ImportTab);
            app.ImportyourmassspectrometrydatatobeginLabel.Text = 'Import your mass spectrometry data to begin :)';
            app.Import_ImportButton = uibutton(app.ImportTab, 'push');
            app.Import_ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_Pushed, true);
            app.Import_ImportButton.Text = 'Import';
            app.CreateNewProjectPanel = uipanel(app.ImportTab);
            app.CreateNewProjectPanel.Title = 'Create New Project';
            app.ProjectNameEditFieldLabel = uilabel(app.CreateNewProjectPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.CreateNewProjectPanel, 'text');
            app.DataTypesButtonGroup = uibuttongroup(app.CreateNewProjectPanel);
            app.DataTypesButtonGroup.Title = 'Data Types';
            app.MassSpectra1DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.MassSpectra1DButton.Text = 'Mass Spectra (1D)';
            app.MassSpectra1DButton.Value = true;
            app.Imaging2DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.Imaging2DButton.Text = 'Imaging (2D)';
            app.DimensionLabel = uilabel(app.CreateNewProjectPanel);
            app.DimensionLabel.Text = 'Dimension';
            app.WidthField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.WidthField.Editable = 'off';
            app.xLabel = uilabel(app.CreateNewProjectPanel);
            app.xLabel.Text = 'x';
            app.HeightField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.HeightField.Editable = 'off';
            app.Image = uiimage(app.CreateNewProjectPanel);
            app.Image.Tooltip = {'Dimension of your data. In case of imaging data, input " width x height ".'};
            app.Image.ImageSource = 'info_icon.png';
            app.NumberofMassSpectraLabel = uilabel(app.CreateNewProjectPanel);
            app.NumberofMassSpectraLabel.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.NumberofMassSpectraEditField.Editable = 'off';
            app.Import_CreateProjectButton = uibutton(app.CreateNewProjectPanel, 'push');
            app.Import_CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @Import_CreateProjectButtonPushed, true);
            app.Import_CreateProjectButton.Text = 'Create';
            app.RecentFilesPanel = uipanel(app.ImportTab);
            app.RecentFilesPanel.Title = 'Recent Files';
            app.ListBox = uilistbox(app.RecentFilesPanel);
            app.OpenButton = uibutton(app.RecentFilesPanel, 'push');
            app.OpenButton.Text = 'Open';
            app.ImportStatusLabel = uilabel(app.ImportTab);
            app.ImportStatusLabel.Text = '';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.PreProcessingToolLabel = uilabel(app.PreprocessingTab);
            app.PreProcessingToolLabel.Text = 'Pre-Processing Tool';
            app.BaselineCorrectionPanel = uipanel(app.PreprocessingTab);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.WindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.Preprocessing_WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_WindowSizeEditField.Value = 0.5;
            app.StepsizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.Preprocessing_StepsizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_StepsizeEditField.Value = 0.5;
            app.QuantilevalueEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.QuantilevalueEditFieldLabel.Text = 'Quantile value';
            app.Preprocessing_QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_QuantilevalueEditField.Value = 0.5;
            app.PeakAlignmentPanel = uipanel(app.PreprocessingTab);
            app.PeakAlignmentPanel.Title = 'Peak Alignment';
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.Preprocessing_ReferenceSpectrumEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_ReferenceSpectrumEditField.Value = 2;
            app.MinimumsegementsizeallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MinimumsegementsizeallowedEditFieldLabel.Text = 'Minimum segement size allowed';
            app.Preprocessing_MinimumsegementsizeallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MinimumsegementsizeallowedEditField.Value = 10;
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.Preprocessing_MaximumshiftallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MaximumshiftallowedEditField.Value = 50;
            app.PlotPanel = uipanel(app.PreprocessingTab);
            app.PlotPanel.Title = 'Plot';
            app.SpectrumtodisplayEditFieldLabel = uilabel(app.PlotPanel);
            app.SpectrumtodisplayEditFieldLabel.Text = 'Spectrum to display';
            app.Preprocessing_SpectrumtodisplayEditField = uieditfield(app.PlotPanel, 'text');
            app.Preprocessing_SectionofinterestLabel = uilabel(app.PlotPanel);
            app.Preprocessing_SectionofinterestLabel.Text = 'Section of interest:';
            app.StartingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.Preprocessing_StartingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.EndingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.Preprocessing_EndingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.Preprocessing_PlotButton = uibutton(app.PlotPanel, 'push');
            app.Preprocessing_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_PlotButtonPushed, true);
            app.Preprocessing_PlotButton.Text = 'Plot';
            app.Preprocessing_ExportButton = uibutton(app.PlotPanel, 'push');
            app.Preprocessing_ExportButton.Text = 'Export';
            app.Preprocessing_SaveButton = uibutton(app.PlotPanel, 'push');
            app.Preprocessing_SaveButton.Text = 'Save & Continue';
            app.Preprocessing_PreprocessedMSPlot = uiaxes(app.PreprocessingTab);
            title(app.Preprocessing_PreprocessedMSPlot, 'Pre-Processed Mass Spectra')
            xlabel(app.Preprocessing_PreprocessedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Preprocessing_PreprocessedMSPlot, 'Relative Intensity')
            app.Preprocessing_PreprocessedMSPlot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Preprocessing_RawMSPlot = uiaxes(app.PreprocessingTab);
            title(app.Preprocessing_RawMSPlot, 'Raw Mass Spectra')
            xlabel(app.Preprocessing_RawMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Preprocessing_RawMSPlot, 'Relative Intensity')
            app.Preprocessing_RawMSPlot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.DataNormalizationTab = uitab(app.TabGroup);
            app.DataNormalizationTab.Title = 'Data Normalization';
            app.RawDataPlot = uiaxes(app.DataNormalizationTab);
            title(app.RawDataPlot, 'Raw Data')
            xlabel(app.RawDataPlot, 'Mass/Charge (m/z)')
            ylabel(app.RawDataPlot, 'Relative Intensity')
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.DataNormalizationTab);
            app.NormalizationMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationMethodsButtonGroupSelectionChanged, true);
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.ioncountsButton.Text = 'ion counts';
            app.ioncountsButton.Value = true;
            app.ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.ReferencePeakButton.Text = 'Reference Peak';
            app.PeakSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.PeakSpinnerLabel.Text = 'Peak';
            app.PeakSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.PeakSpinner.Limits = [0 76];
            app.PeakSpinner.ValueChangedFcn = createCallbackFcn(app, @PeakSpinnerValueChanged, true);
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinner.Limits = [1 Inf];
            app.pvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @pvalueSpinnerValueChanged, true);
            app.pvalueSpinner.Value = 1;
            app.ExclusionofmassrangesCheckBox = uicheckbox(app.NormalizationMethodsButtonGroup);
            app.ExclusionofmassrangesCheckBox.Enable = 'off';
            app.ExclusionofmassrangesCheckBox.Text = 'Exclusion of mass ranges';
            app.LowerEditFieldLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.LowerEditFieldLabel.Enable = 'off';
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.LowerEditField.Enable = 'off';
            app.UpperEditFieldLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.UpperEditFieldLabel.Enable = 'off';
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.UpperEditField.Enable = 'off';
            app.NowSelectLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.NowSelectLabel.Text = 'Now Select';
            app.pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.pnormButton.Text = 'p-norm';
            app.MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.MedianButton.Text = 'Median';
            app.NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.NoiseLevelButton.Text = 'Noise Level';
            app.NormalizedDataPlot = uiaxes(app.DataNormalizationTab);
            title(app.NormalizedDataPlot, 'Normalized Data')
            xlabel(app.NormalizedDataPlot, 'Mass/Charge (m/z)')
            ylabel(app.NormalizedDataPlot, 'Relative Intensity')
            app.ViewOptionButtonGroup = uibuttongroup(app.DataNormalizationTab);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = ' View Option';
            app.AllSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.AllSpectraButton.Text = 'All Spectra';
            app.AllSpectraButton.Value = true;
            app.SingleSpectrumButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.SingleSpectrumButton.Text = 'Single Spectrum';
            app.SamplePointSpinnerLabel = uilabel(app.ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel.Text = 'Sample Point';
            app.SamplePointSpinner = uispinner(app.ViewOptionButtonGroup);
            app.SamplePointSpinner.Limits = [1 80];
            app.SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplePointSpinnerValueChanged, true);
            app.SamplePointSpinner.Value = 1;
            app.SelectDataTable = uitable(app.ViewOptionButtonGroup);
            app.SelectDataTable.ColumnName = {'Sample Point'; 'Display'};
            app.SelectDataTable.RowName = {};
            app.SelectDataTable.ColumnSortable = true;
            app.SelectDataTable.ColumnEditable = [false true];
            app.SelectDataTable.CellEditCallback = createCallbackFcn(app, @SelectDataTableCellEdit, true);
            app.SelectDataTable.Enable = 'off';
            app.MultipleSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.MultipleSpectraButton.Text = 'Multiple Spectra';
            app.ExportFilesPanel = uipanel(app.DataNormalizationTab);
            app.ExportFilesPanel.Title = 'Export Files';
            app.GeneratePDFReportButton = uibutton(app.ExportFilesPanel, 'push');
            app.GeneratePDFReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFReportButtonPushed, true);
            app.GeneratePDFReportButton.Text = 'Generate PDF Report';
            app.ExportCSVNormalizedDataButton = uibutton(app.ExportFilesPanel, 'push');
            app.ExportCSVNormalizedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVNormalizedDataButtonPushed, true);
            app.ExportCSVNormalizedDataButton.Text = {'Export  CSV'; 'Normalized Data '};
            app.UITable = uitable(app.DataNormalizationTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PeakDetectionBinningTab = uitab(app.TabGroup);
            app.PeakDetectionBinningTab.Title = 'Peak Detection & Binning';
            app.UIAxes_6 = uiaxes(app.PeakDetectionBinningTab);
            title(app.UIAxes_6, 'Peak Detection')
            xlabel(app.UIAxes_6, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes_6, 'Relative Intensity')
            app.UIAxes_7 = uiaxes(app.PeakDetectionBinningTab);
            title(app.UIAxes_7, 'Peak Binning')
            xlabel(app.UIAxes_7, 'Mass/Charge (m/z)')
            ylabel(app.UIAxes_7, 'Relative Intensity')
            app.PeakDetectionPanel = uipanel(app.PeakDetectionBinningTab);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.BASEEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.BASEEditFieldLabel.Text = 'BASE';
            app.BASEEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.BASEEditField.Value = 12;
            app.HEIGHTFILTEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.HEIGHTFILTEREditFieldLabel.Text = 'HEIGHTFILTER';
            app.HEIGHTFILTEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.HEIGHTFILTEREditField.Value = 1;
            app.MULTIPLIEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.MULTIPLIEREditFieldLabel.Text = 'MULTIPLIER';
            app.MULTIPLIEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.MULTIPLIEREditField.Value = 10;
            app.PeakThresholdEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.PeakThresholdEditFieldLabel.Text = 'Peak Threshold';
            app.PeakThresholdEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.PeakThresholdEditField.Value = 300;
            app.SpectrumtodisplayEditField_2Label = uilabel(app.PeakDetectionPanel);
            app.SpectrumtodisplayEditField_2Label.Text = 'Spectrum to display';
            app.SpectrumtodisplayEditField_2 = uieditfield(app.PeakDetectionPanel, 'text');
            app.BiningPanel = uipanel(app.PeakDetectionBinningTab);
            app.BiningPanel.Title = 'Bining';
            app.CUTOFFEditFieldLabel = uilabel(app.BiningPanel);
            app.CUTOFFEditFieldLabel.Text = 'CUTOFF';
            app.CUTOFFEditField = uieditfield(app.BiningPanel, 'numeric');
            app.MethodDropDownLabel = uilabel(app.BiningPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.BiningPanel);
            app.MethodDropDown.Items = {'Hierarchical Clustering', 'Dynamic Programming'};
            app.MethodDropDown.ItemsData = {'1'};
            app.MethodDropDown.Value = '1';
            app.CriterionDropDownLabel = uilabel(app.BiningPanel);
            app.CriterionDropDownLabel.Text = 'Criterion';
            app.CriterionDropDown = uidropdown(app.BiningPanel);
            app.CriterionDropDown.Items = {'DISTANCE'};
            app.CriterionDropDown.ItemsData = {'distance'};
            app.CriterionDropDown.Value = 'distance';
            app.SpectrumtodisplayEditField_3Label = uilabel(app.BiningPanel);
            app.SpectrumtodisplayEditField_3Label.Text = 'Spectrum to display';
            app.SpectrumtodisplayEditField_3 = uieditfield(app.BiningPanel, 'text');
            app.UITable_2 = uitable(app.PeakDetectionBinningTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.ExportCSVButton = uibutton(app.PeakDetectionBinningTab, 'push');
            app.ExportCSVButton.Text = {'Export  CSV'; ' '};
            app.PeakDetectionandPeakBinningLabel = uilabel(app.PeakDetectionBinningTab);
            app.PeakDetectionandPeakBinningLabel.Text = 'Peak Detection and Peak Binning';
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.Imaging_NormalizedDataPlot = uiaxes(app.ImagingTab);
            title(app.Imaging_NormalizedDataPlot, 'Normalized Data')
            xlabel(app.Imaging_NormalizedDataPlot, 'm/z')
            ylabel(app.Imaging_NormalizedDataPlot, 'Relative Intensity')
            app.FeaturePeakLabel = uilabel(app.ImagingTab);
            app.FeaturePeakLabel.Text = {'Feature'; 'Peak'};
            app.FeaturePeakSlider = uislider(app.ImagingTab);
            app.FeaturePeakSlider.Limits = [1 76];
            app.FeaturePeakSlider.ValueChangedFcn = createCallbackFcn(app, @FeaturePeakSliderValueChanged, true);
            app.FeaturePeakSlider.MinorTicks = [];
            app.FeaturePeakSlider.Value = 1;
            app.SelectedPeakLabel = uilabel(app.ImagingTab);
            app.SelectedPeakLabel.Text = 'Selected Peak:';
            app.Imaging_Display = uiaxes(app.ImagingTab);
            title(app.Imaging_Display, '')
            xlabel(app.Imaging_Display, '')
            ylabel(app.Imaging_Display, '')
            app.ColorMapDropDownLabel = uilabel(app.ImagingTab);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColorMapDropDown = uidropdown(app.ImagingTab);
            app.ColorMapDropDown.Items = {'gray', 'parula', 'hsv', 'winter'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'gray';
            app.Imaging_SelectedDataPlot = uiaxes(app.ImagingTab);
            title(app.Imaging_SelectedDataPlot, 'Selected Datapoint')
            xlabel(app.Imaging_SelectedDataPlot, 'm/z')
            ylabel(app.Imaging_SelectedDataPlot, 'Relative Intensity')
            app.ColorScaleOptionListBoxLabel = uilabel(app.ImagingTab);
            app.ColorScaleOptionListBoxLabel.Text = {'Color Scale'; 'Option'};
            app.ColorScaleOptionListBox = uilistbox(app.ImagingTab);
            app.ColorScaleOptionListBox.Items = {'Full Scale', '0 to max', 'custom'};
            app.ColorScaleOptionListBox.Value = 'Full Scale';
            app.AnalyticsTab = uitab(app.TabGroup);
            app.AnalyticsTab.Title = 'Analytics';
            app.SelectClassTable = uitable(app.AnalyticsTab);
            app.SelectClassTable.ColumnName = {'Class'; 'Select'};
            app.SelectClassTable.RowName = {};
            app.SelectClassTable.ColumnEditable = [false true];
            app.SelectClassTable.CellEditCallback = createCallbackFcn(app, @SelectClassTableCellEdit, true);
            app.Imaging_Display_2 = uiaxes(app.AnalyticsTab);
            title(app.Imaging_Display_2, '')
            xlabel(app.Imaging_Display_2, '')
            ylabel(app.Imaging_Display_2, '')
            app.UIAxes = uiaxes(app.AnalyticsTab);
            title(app.UIAxes, 'Class 1')
            xlabel(app.UIAxes, 'intensity')
            ylabel(app.UIAxes, 'count')
            app.UIAxes_2 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes_2, 'Class 2')
            xlabel(app.UIAxes_2, 'intensity')
            ylabel(app.UIAxes_2, 'count')
            app.UIAxes_3 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes_3, 'Class 3')
            xlabel(app.UIAxes_3, 'intensity')
            ylabel(app.UIAxes_3, 'count')
            app.UIAxes2 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes2, 'Sample Point')
            xlabel(app.UIAxes2, 'm/z')
            ylabel(app.UIAxes2, 'Intensity')
            app.ProjectInfoTab = uitab(app.TabGroup);
            app.ProjectInfoTab.Title = 'Project Info';
            app.Panel = uipanel(app.ProjectInfoTab);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.MSPECAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSpecMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSPECAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSPECAppUIFigure)
        end
    end
end